classdef visualize_signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisualizeCHB_MIT_EEGUIFigure  matlab.ui.Figure
        SignalFeaturePanel            matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        FileNameEditField_2           matlab.ui.control.EditField
        FileNameEditField_2Label      matlab.ui.control.Label
        ImportFeatureFileButton       matlab.ui.control.Button
        UITable_4                     matlab.ui.control.Table
        UITable_3                     matlab.ui.control.Table
        UIAxes_4                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        time_spinner                  matlab.ui.control.Spinner
        TimeLabel                     matlab.ui.control.Label
        window_size_spinner           matlab.ui.control.Spinner
        windowsizeSpinnerLabel        matlab.ui.control.Label
        TimeSlider                    matlab.ui.control.Slider
        TimeSliderLabel               matlab.ui.control.Label
        CHBMITEEGPanel                matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        UITable_2                     matlab.ui.control.Table
        UITable                       matlab.ui.control.Table
        FileNameEditField             matlab.ui.control.EditField
        FileNameEditFieldLabel        matlab.ui.control.Label
        ImportEDFFileButton           matlab.ui.control.Button
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        EEG
        info
        start_time
        record_time
        feature_mat
        feature_labels
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportEDFFileButton
        function ImportEDFFileButtonPushed(app, event)
            [file_name, file_path]=uigetfile('*.edf');
            if isequal(file_name,0)
                %disp('User selected Cancel');
            else
                %disp(['User selected ', fullfile(file_path,file_name)]);
            
                app.FileNameEditField.Value = fullfile(file_path,file_name);
                
                [data, anns] = edfread(fullfile(file_path,file_name));
                app.EEG = cell2mat(data{:,:})';
                app.info = edfinfo(fullfile(file_path,file_name));
                app.start_time = datetime(app.info.StartDate+"."+app.info.StartTime,"InputFormat","dd.MM.yy.HH.mm.ss");
                                
                t=table;
                t.SingalLabel=app.info.SignalLabels;
                t.Visiable=repelem([false],size(app.info.SignalLabels,1))';
                app.UITable.Data=t;
                app.UITable_2.Data=t;
                
                fs = app.info.NumSamples(1)/seconds(app.info.DataRecordDuration);
                app.record_time = (0:duration(milliseconds(1e3/fs)):seconds(size(data,1))-duration(milliseconds(1e3/fs)));
                
                plot(app.UIAxes,app.record_time,zeros(size(app.record_time)));
                xlim(app.UIAxes,[seconds(app.TimeSlider.Value) seconds(app.TimeSlider.Value+app.window_size_spinner.Value)]);
                plot(app.UIAxes_2,app.record_time,zeros(size(app.record_time)));
                xlim(app.UIAxes_2,[seconds(app.TimeSlider.Value) seconds(app.TimeSlider.Value+app.window_size_spinner.Value)]);
                plot(app.UIAxes_3,app.record_time,zeros(size(app.record_time)));
                xlim(app.UIAxes_3,[seconds(app.TimeSlider.Value) seconds(app.TimeSlider.Value+app.window_size_spinner.Value)]);
                plot(app.UIAxes_4,app.record_time,zeros(size(app.record_time)));
                xlim(app.UIAxes_4,[seconds(app.TimeSlider.Value) seconds(app.TimeSlider.Value+app.window_size_spinner.Value)]);
                
                app.TimeSlider.Limits = [0 size(data,1)];
                app.time_spinner.Limits = [0 size(data,1)];
                
                app.TimeSlider.Enable = 'on';
                app.window_size_spinner.Enable = 'on';
                app.time_spinner.Enable = 'on';
                app.ImportFeatureFileButton.Enable = 'on';
            end

        end

        % Value changed function: window_size_spinner
        function window_size_spinnerValueChanged(app, event)
            value = app.window_size_spinner.Value;
            xlim(app.UIAxes,[seconds(app.TimeSlider.Value) seconds(app.TimeSlider.Value+value)]);
            xlim(app.UIAxes_2,[seconds(app.TimeSlider.Value) seconds(app.TimeSlider.Value+value)]);
            xlim(app.UIAxes_3,[seconds(app.TimeSlider.Value) seconds(app.TimeSlider.Value+value)]);
            xlim(app.UIAxes_4,[seconds(app.TimeSlider.Value) seconds(app.TimeSlider.Value+value)]);
            
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.time_spinner.Value = changingValue;
            xlim(app.UIAxes,[seconds(changingValue) seconds(changingValue+app.window_size_spinner.Value)]);
            xlim(app.UIAxes_2,[seconds(changingValue) seconds(changingValue+app.window_size_spinner.Value)]);
            xlim(app.UIAxes_3,[seconds(changingValue) seconds(changingValue+app.window_size_spinner.Value)]);
            xlim(app.UIAxes_4,[seconds(changingValue) seconds(changingValue+app.window_size_spinner.Value)]);
            
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            value = app.TimeSlider.Value;
            
        end

        % Value changing function: time_spinner
        function time_spinnerValueChanging(app, event)
            changingValue = event.Value;
            xlim(app.UIAxes,[seconds(changingValue) seconds(changingValue+app.window_size_spinner.Value)]);
            xlim(app.UIAxes_2,[seconds(changingValue) seconds(changingValue+app.window_size_spinner.Value)]);
            xlim(app.UIAxes_3,[seconds(changingValue) seconds(changingValue+app.window_size_spinner.Value)]);
            xlim(app.UIAxes_4,[seconds(changingValue) seconds(changingValue+app.window_size_spinner.Value)]);
            app.TimeSlider.Value = changingValue;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            cla(app.UIAxes);
            legend_label =[];
            hold(app.UIAxes,"on");
            for i = 1:size(app.UITable.Data,1)
                if app.UITable.Data{i,2}
                    plot(app.UIAxes,app.record_time,app.EEG(i,:));
                    legend_label =[legend_label app.UITable.Data{i,1}];
                end
            end
            for i = 1:size(app.info.Annotations.Onset,1)
                beginning_of_seizure = app.info.Annotations.Onset(i);
                end_of_seizure = app.info.Annotations.Onset(i)+app.info.Annotations.Duration(i);
                xline(app.UIAxes, beginning_of_seizure, 'Color', '#A2142F', 'LineWidth', 2);
                xline(app.UIAxes, end_of_seizure,'Color', '#77AC30', 'LineWidth', 2);
            end
            hold(app.UIAxes,"off");
            xlim(app.UIAxes,[seconds(app.TimeSlider.Value) +seconds(app.TimeSlider.Value+app.window_size_spinner.Value)]);
            if ~isempty(legend_label)
                legend(app.UIAxes,legend_label);
            else
                legend(app.UIAxes,'off');
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Cell edit callback: UITable_2
        function UITable_2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            cla(app.UIAxes_2);
            legend_label =[];
            hold(app.UIAxes_2,"on");
            for i = 1:size(app.UITable_2.Data,1)
                if app.UITable_2.Data{i,2}
                    plot(app.UIAxes_2,app.record_time,app.EEG(i,:));
                    legend_label =[legend_label app.UITable_2.Data{i,1}];
                end
            end
            for i = 1:size(app.info.Annotations.Onset,1)
                beginning_of_seizure = app.info.Annotations.Onset(i);
                end_of_seizure = app.info.Annotations.Onset(i)+app.info.Annotations.Duration(i);
                xline(app.UIAxes_2, beginning_of_seizure, 'Color', '#A2142F', 'LineWidth', 2);
                xline(app.UIAxes_2, end_of_seizure,'Color', '#77AC30', 'LineWidth', 2);
            end
            hold(app.UIAxes_2,"off");
            xlim(app.UIAxes_2,[seconds(app.TimeSlider.Value) +seconds(app.TimeSlider.Value+app.window_size_spinner.Value)]);
            if ~isempty(legend_label)
                legend(app.UIAxes_2,legend_label);
            else
                legend(app.UIAxes_2,'off');
            end
        end

        % Button pushed function: ImportFeatureFileButton
        function ImportFeatureFileButtonPushed(app, event)
            [file_name, file_path]=uigetfile('*.dat');
            if isequal(file_name,0)
                %disp('User selected Cancel');
            else
                %disp(['User selected ', fullfile(file_path,file_name)]);
            
                app.FileNameEditField_2.Value = fullfile(file_path,file_name);
                feature_table = readtable(fullfile(file_path,file_name));
                app.feature_labels=string(feature_table.Properties.VariableNames)';
                app.feature_mat=table2array(feature_table)';

                t=table;
                t.SingalLabel=string(feature_table.Properties.VariableNames)';
                t.Visiable=repelem([false],size(feature_table.Properties.VariableNames,2))';
                app.UITable_3.Data=t;
                app.UITable_4.Data=t;

            end
        end

        % Cell edit callback: UITable_3
        function UITable_3CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            cla(app.UIAxes_3);
            legend_label =[];
            hold(app.UIAxes_3,"on");
            for i = 1:size(app.UITable_3.Data,1)
                if app.UITable_3.Data{i,2}
                    plot(app.UIAxes_3,app.record_time,app.feature_mat(i,:));
                    legend_label =[legend_label app.UITable_3.Data{i,1}];
                end
            end
            for i = 1:size(app.info.Annotations.Onset,1)
                beginning_of_seizure = app.info.Annotations.Onset(i);
                end_of_seizure = app.info.Annotations.Onset(i)+app.info.Annotations.Duration(i);
                xline(app.UIAxes_3, beginning_of_seizure, 'Color', '#A2142F', 'LineWidth', 2);
                xline(app.UIAxes_3, end_of_seizure,'Color', '#77AC30', 'LineWidth', 2);
            end
            hold(app.UIAxes_3,"off");
            xlim(app.UIAxes_3,[seconds(app.TimeSlider.Value) +seconds(app.TimeSlider.Value+app.window_size_spinner.Value)]);
            if ~isempty(legend_label)
                legend(app.UIAxes_3,legend_label);
            else
                legend(app.UIAxes_3,'off');
            end
        end

        % Cell edit callback: UITable_4
        function UITable_4CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            cla(app.UIAxes_4);
            legend_label =[];
            hold(app.UIAxes_4,"on");
            for i = 1:size(app.UITable_4.Data,1)
                if app.UITable_4.Data{i,2}
                    plot(app.UIAxes_4,app.record_time,app.feature_mat(i,:));
                    legend_label =[legend_label app.UITable_4.Data{i,1}];
                end
            end
            for i = 1:size(app.info.Annotations.Onset,1)
                beginning_of_seizure = app.info.Annotations.Onset(i);
                end_of_seizure = app.info.Annotations.Onset(i)+app.info.Annotations.Duration(i);
                xline(app.UIAxes_4, beginning_of_seizure, 'Color', '#A2142F', 'LineWidth', 2);
                xline(app.UIAxes_4, end_of_seizure,'Color', '#77AC30', 'LineWidth', 2);
            end
            hold(app.UIAxes_4,"off");
            xlim(app.UIAxes_4,[seconds(app.TimeSlider.Value) +seconds(app.TimeSlider.Value+app.window_size_spinner.Value)]);
            if ~isempty(legend_label)
                legend(app.UIAxes_4,legend_label);
            else
                legend(app.UIAxes_4,'off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VisualizeCHB_MIT_EEGUIFigure = uifigure('Visible', 'off');
            app.VisualizeCHB_MIT_EEGUIFigure.Name = 'Visualize CHB_MIT_EEG';
            app.CHBMITEEGPanel = uipanel(app.VisualizeCHB_MIT_EEGUIFigure);
            app.CHBMITEEGPanel.Title = 'CHB-MIT EEG';
            app.GridLayout2 = uigridlayout(app.CHBMITEEGPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 'Time')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes_2, 'Time')
            zlabel(app.UIAxes_2, 'Z')
            app.ImportEDFFileButton = uibutton(app.GridLayout2, 'push');
            app.ImportEDFFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportEDFFileButtonPushed, true);
            app.ImportEDFFileButton.Text = 'Import EDF File';
            app.FileNameEditFieldLabel = uilabel(app.GridLayout2);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.GridLayout2, 'text');
            app.FileNameEditField.Editable = 'off';
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Singal Label'; 'Enable'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable_2 = uitable(app.GridLayout2);
            app.UITable_2.ColumnName = {'Singal Label'; 'Enable'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = [false true];
            app.UITable_2.CellEditCallback = createCallbackFcn(app, @UITable_2CellEdit, true);
            app.TimeSliderLabel = uilabel(app.VisualizeCHB_MIT_EEGUIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.VisualizeCHB_MIT_EEGUIFigure);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.TimeSlider.Enable = 'off';
            app.windowsizeSpinnerLabel = uilabel(app.VisualizeCHB_MIT_EEGUIFigure);
            app.windowsizeSpinnerLabel.Text = 'window size';
            app.window_size_spinner = uispinner(app.VisualizeCHB_MIT_EEGUIFigure);
            app.window_size_spinner.Limits = [1 Inf];
            app.window_size_spinner.ValueChangedFcn = createCallbackFcn(app, @window_size_spinnerValueChanged, true);
            app.window_size_spinner.Enable = 'off';
            app.window_size_spinner.Value = 5;
            app.TimeLabel = uilabel(app.VisualizeCHB_MIT_EEGUIFigure);
            app.TimeLabel.Text = 'Time';
            app.time_spinner = uispinner(app.VisualizeCHB_MIT_EEGUIFigure);
            app.time_spinner.ValueChangingFcn = createCallbackFcn(app, @time_spinnerValueChanging, true);
            app.time_spinner.Limits = [0 Inf];
            app.time_spinner.Enable = 'off';
            app.SignalFeaturePanel = uipanel(app.VisualizeCHB_MIT_EEGUIFigure);
            app.SignalFeaturePanel.Title = 'Signal Feature';
            app.GridLayout = uigridlayout(app.SignalFeaturePanel);
            app.UIAxes_3 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_3, 'Time')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_4, 'Time')
            zlabel(app.UIAxes_4, 'Z')
            app.UITable_3 = uitable(app.GridLayout);
            app.UITable_3.ColumnName = {'Singal Label'; 'Enable'};
            app.UITable_3.RowName = {};
            app.UITable_3.ColumnEditable = [false true];
            app.UITable_3.CellEditCallback = createCallbackFcn(app, @UITable_3CellEdit, true);
            app.UITable_4 = uitable(app.GridLayout);
            app.UITable_4.ColumnName = {'Singal Label'; 'Enable'};
            app.UITable_4.RowName = {};
            app.UITable_4.ColumnEditable = [false true];
            app.UITable_4.CellEditCallback = createCallbackFcn(app, @UITable_4CellEdit, true);
            app.ImportFeatureFileButton = uibutton(app.GridLayout, 'push');
            app.ImportFeatureFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFeatureFileButtonPushed, true);
            app.ImportFeatureFileButton.Enable = 'off';
            app.ImportFeatureFileButton.Text = 'Import Feature File';
            app.FileNameEditField_2Label = uilabel(app.GridLayout);
            app.FileNameEditField_2Label.Text = 'File Name';
            app.FileNameEditField_2 = uieditfield(app.GridLayout, 'text');
            app.FileNameEditField_2.Editable = 'off';
            % Show the figure after all components are created
            app.VisualizeCHB_MIT_EEGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualize_signal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VisualizeCHB_MIT_EEGUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisualizeCHB_MIT_EEGUIFigure)
        end
    end
end